classdef Bdot_AxialScan_V1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        KILLSCANButton                 matlab.ui.control.Button
        OscilloscopeSettingsPanel      matlab.ui.container.Panel
        Ch3PPRangeVEditField           matlab.ui.control.NumericEditField
        Ch3PPRangeVEditFieldLabel      matlab.ui.control.Label
        Ch2PPRangeVEditField           matlab.ui.control.NumericEditField
        Ch2PPRangeVEditFieldLabel      matlab.ui.control.Label
        Ch1PPRangeVEditField           matlab.ui.control.NumericEditField
        Ch1PPRangeVEditFieldLabel      matlab.ui.control.Label
        ProbeRadialPositionPanel       matlab.ui.container.Panel
        SuperRotateButton              matlab.ui.control.Button
        RotateButton                   matlab.ui.control.Button
        RotatebydegEditField           matlab.ui.control.NumericEditField
        RotatebydegEditFieldLabel      matlab.ui.control.Label
        Direction_3                    matlab.ui.control.DropDown
        DirectionLabel_4               matlab.ui.control.Label
        SolenoidsPositionControlPanel  matlab.ui.container.Panel
        SolMoveButton                  matlab.ui.control.Button
        SolMovebycmEditField           matlab.ui.control.NumericEditField
        MovebycmEditField_2Label       matlab.ui.control.Label
        SolDirection                   matlab.ui.control.DropDown
        DirectionLabel_3               matlab.ui.control.Label
        SaveDataButton                 matlab.ui.control.Button
        ChangeButton                   matlab.ui.control.Button
        SaveDirectoryEditField         matlab.ui.control.EditField
        SaveDirectoryEditFieldLabel    matlab.ui.control.Label
        ProbeIndependantPositionPanel  matlab.ui.container.Panel
        MoveButton                     matlab.ui.control.Button
        GoHomeButton                   matlab.ui.control.Button
        MovebycmEditField              matlab.ui.control.NumericEditField
        MovebycmEditFieldLabel         matlab.ui.control.Label
        Direction_2                    matlab.ui.control.DropDown
        DirectionLabel_2               matlab.ui.control.Label
        PlasmaParametersPanel          matlab.ui.container.Panel
        Frequency50WMHzEditField       matlab.ui.control.NumericEditField
        Frequency50WMHzEditFieldLabel  matlab.ui.control.Label
        IgnitionOrderDropDown          matlab.ui.control.DropDown
        IgnitionOrderDropDownLabel     matlab.ui.control.Label
        PlasmaOffButton                matlab.ui.control.Button
        MatchingTestPlasmaOnButton     matlab.ui.control.Button
        CoilsCurrentAEditField         matlab.ui.control.NumericEditField
        CoilsCurrentAEditFieldLabel    matlab.ui.control.Label
        Power                          matlab.ui.control.NumericEditField
        RFPowerSetWLabel               matlab.ui.control.Label
        StatusEditField                matlab.ui.control.EditField
        StatusEditFieldLabel           matlab.ui.control.Label
        YougottoMoveitButton           matlab.ui.control.Button
        ProbeScanParametersPanel       matlab.ui.container.Panel
        GoHomeWestButton               matlab.ui.control.Button
        GoHomeEastButton               matlab.ui.control.Button
        Direction                      matlab.ui.control.DropDown
        DirectionLabel                 matlab.ui.control.Label
        IncrementField                 matlab.ui.control.NumericEditField
        IncrementcmEditFieldLabel      matlab.ui.control.Label
        EndPositionField               matlab.ui.control.NumericEditField
        EndPositioncmEditFieldLabel    matlab.ui.control.Label
        StartPositionField             matlab.ui.control.NumericEditField
        StartPositioncmEditFieldLabel  matlab.ui.control.Label
        BdotAxialScanv10Label          matlab.ui.control.Label
        InitialiseButton               matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    properties (Access = private)
        Dev;        %Scope session
        RF_PSU;     %RF Power Supply VRG1000A
        DC_PSU;     %DC Solenoid Power Supply EA

        SolArduino;
        ProbArduino;
        ProbePosition;

        Stime;
        VAnt;
        VB;
        VE;

    end

    methods (Access = private)
        
        function results = MoveProbe(app, zIncrement, DirectionP)
            
            app.StatusEditField.Value='Probe Moving';

            zIncrement = num2str(zIncrement);
            DirectionP = num2str(DirectionP);
            % command in the format <MotorID,direction,distance> as string, integer and double
            %L is for the linear motion, R is for the rotation
            %1 means West
            %0 means East
            StepperCommand = strcat("<","L",",",DirectionP,",",zIncrement,">");
            fprintf(app.ProbArduino,StepperCommand);

        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InitialiseButton
        function InitialiseButtonPushed(app, event)
            
            app.StatusEditField.Value='Initialising';
             
%===========Oscilloscope===================================================
            app.Dev = ividev("AgInfiniiVision","USB0::0x2A8D::0x0396::CN58467892::0::INSTR")

%===========DC Solenoid Power Supply=======================================
            app.DC_PSU = [];
            delete(instrfind('Port','COM11'));
            app.DC_PSU = serial('COM11');
            fopen(app.DC_PSU);
            fprintf(app.DC_PSU, 'SYSTEM:LOCK ON');                          %Enable remote control
            fprintf(app.DC_PSU, ['SOURCE:VOLTAGE MAX']);                    %Set the voltage to the maximum to operate in current limited mode
            
%============RF Power Supply===============================================   
            app.RF_PSU = [];
            delete(instrfind('Port','COM6'));
            app.RF_PSU = serial('COM6');
            fopen(app.RF_PSU);
            fprintf(app.RF_PSU, ['SF',num2str(27.12*1e3)]);                 %SET FREQUENCY in kHz, fixed at 27.12MHz
            
%============Solenoids Stepper Motor Arduino===============================
%              app.SolArduino = [];
%              delete(instrfind('Port','COM7'));
%              app.SolArduino = serial('COM7','BAUD',9600);
%              fopen(app.SolArduino);
            
%============Probe Stepper Motor Arduino===================================
            app.ProbArduino = [];
            delete(instrfind('Port','COM12'));
            app.ProbArduino = serial('COM12','BAUD',9600);
            fopen(app.ProbArduino); 
            
            app.StatusEditField.Value='Initialised';
            
        end

        % Button pushed function: YougottoMoveitButton
        function YougottoMoveitButtonPushed(app, event)
            
           app.ProbePosition=[];
           app.Stime=[];
           app.VAnt=[];
           app.VB=[];
           app.VE=[];

           cla(app.UIAxes)

           app.ProbePosition=app.StartPositionField.Value:app.IncrementField.Value:app.EndPositionField.Value;
           Nmeas=length(app.ProbePosition);
%            h = animatedline(app.UIAxes,'LineStyle','--','Marker','*','MarkerEdgeColor','r','MarkerFaceColor','r');

           
           for i=1:Nmeas
      
                %Plasma Ignition===========================================
                Order = app.IgnitionOrderDropDown.Value;
                Curr = app.CoilsCurrentAEditField.Value;
                Pset = app.Power.Value;
                Ftrig = app.Frequency50WMHzEditField.Value;

                app.StatusEditField.Value='Discharge On';
                if Order 
                    
                    %Set solenoids DC power supply
                    fprintf(app.DC_PSU, ['SOURCE:CURRENT ',num2str(Curr),'A']);     %Preset the output current
                    fprintf(app.DC_PSU, ['OUTPUT ',num2str(1)]);                    %Switch the output on: 1=On, 0=Off
                    pause(0.1)
                    
                    %Set RF power supply                                            
                    %Often it will be impossible to trigger the plasma from 0W to the
                    %desired power with a good match.
                    %Instead, set the matching for the desired power and then
                    %tune the frequency for the plasma to trigger in high density mode at 50W. 
                    %Then reset the power and frequency to the desired one.
                    fprintf(app.RF_PSU, ['SP',sprintf('%04d',50)]);                 %SET RF POWER need to be formated as a XXXX string
                    fprintf(app.RF_PSU, ['SF',num2str(Ftrig*1e3)]);                 %Set the frequency at which we can trigger the plasma at 50W
                    fprintf(app.RF_PSU, 'ER');                                      %ENABLE RF
                    pause(0.25)
                    fprintf(app.RF_PSU, ['SP',sprintf('%04d',Pset)]);               %SET RF POWER need to be formated as a XXXX string
                    pause(0.25)
                    fprintf(app.RF_PSU, ['SF',num2str(27.12*1e3)]);                 %SET FREQUENCY in kHz, fixed at 27.12MHz
                    
                    pause(2.5)
                    
                else
                                        
                    %Set RF power supply                                       %[W]
                    fprintf(app.RF_PSU, ['SP',sprintf('%04d',Pset)]);               %SET RF POWER need to be formated as a XXXX string
                    fprintf(app.RF_PSU, ['SF',num2str(Ftrig*1e3)]);                 %SET FREQUENCY in kHz
                    fprintf(app.RF_PSU, 'ER');                                      %ENABLE RF
                    pause(0.25)
                    
                    %Set solenoids DC power supply
                    %Ramping up the current from 0 to Curr seems to help with
                    %getting a discharge with perfect match
                    RampCurr = linspace(0,Curr,50);
                    for n=1:length(RampCurr)
                        %Set solenoids DC power supply
                        fprintf(app.DC_PSU, ['SOURCE:CURRENT ',num2str(RampCurr(n)),'A']);     %Preset the output current
                        fprintf(app.DC_PSU, ['OUTPUT ',num2str(1)]);                  %Switch the output on: 1=On, 0=Off
                        pause(5e-2)
                    end
                    fprintf(app.RF_PSU, ['SF',num2str(27.12*1e3)]);                 %SET FREQUENCY in kHz, fixed at 27.12MHz
                    
                    pause(2.5)
                    
                end
                
                %Configure Scope===========================================
                %number of points that the oscilloscope acquires
                recordLength = actualRecordLength(app.Dev);
                %Enable channels
                app.Dev.Channel("Channel1").ChannelEnabled = true;
                app.Dev.Channel("Channel2").ChannelEnabled = true;
                app.Dev.Channel("Channel3").ChannelEnabled = true;

                %Set Channels
                app.Dev.Channel("Channel1").ProbeAttenuation = 100;
                app.Dev.Channel("Channel1").VerticalRange = app.Ch1PPRangeVEditField.Value;   %[V]
                app.Dev.Channel("Channel2").ProbeAttenuation = 1;
                app.Dev.Channel("Channel2").VerticalRange = app.Ch2PPRangeVEditField.Value;
                app.Dev.Channel("Channel3").ProbeAttenuation = 1;
                app.Dev.Channel("Channel3").VerticalRange = app.Ch3PPRangeVEditField.Value;

                %Specify the sweep time duration in milliseconds.
                maxTimeMilliseconds = 0.2e-6;

                %Acquire Data==============================================
                app.StatusEditField.Value='Measuring';
                
                %Read a waveform from the oscilloscope.
                [waveformArray1,] = readWaveform(app.Dev,"Channel1",recordLength,maxTimeMilliseconds);
                [waveformArray2,] = readWaveform(app.Dev,"Channel2",recordLength,maxTimeMilliseconds);
                [waveformArray3,] = readWaveform(app.Dev,"Channel3",recordLength,maxTimeMilliseconds);

                %Turn off RF power supply==================================
                fprintf(app.RF_PSU, 'DR');                                      %DISABLE RF
                
                %Turn off DC power suppy===================================
                fprintf(app.DC_PSU, ['OUTPUT ',num2str(0)]);                    %Switch the output on: 1=On, 0=Off
                                
                %Post-processing
                n = numel(waveformArray1);      %number elements in the waveform array
                dt = app.Dev.Acquisition.HorizontalTimePerRecord./app.Dev.Acquisition.HorizontalRecordLength; %Time in between samples and create a time vector.
                app.Stime(:,i) = (0:n-1).* dt;

                app.VAnt(:,i) = waveformArray1./100;
                app.VB(:,i) = waveformArray2;
                app.VE(:,i) = waveformArray3;                
               
                
                plot(app.UIAxes, app.Stime(:,i),app.VAnt(:,i), 'Color','y')
                hold(app.UIAxes, 'on')
                plot(app.UIAxes, app.Stime(:,i),app.VB(:,i), 'Color','g')
                plot(app.UIAxes, app.Stime(:,i),app.VE(:,i), 'Color','b')
                hold(app.UIAxes, 'off')
                
                drawnow

                                
                if i<Nmeas
                    %Move Probe================================================
                    MoveProbe(app,app.IncrementField.Value,app.Direction.Value)
                end
               
           end
           app.StatusEditField.Value='Scan Done';
          
       
        end

        % Button pushed function: MatchingTestPlasmaOnButton
        function MatchingTestPlasmaOnButtonPushed(app, event)
            
            app.StatusEditField.Value='Matching Test';
            
            %In some conditions the plasma does no ignite when the B field
            %is on before the plasma but does if the ignition order is
            %reversed
            Order = app.IgnitionOrderDropDown.Value;
            Curr = app.CoilsCurrentAEditField.Value;
            Pset = app.Power.Value;
            Ftrig = app.Frequency50WMHzEditField.Value;
            
            if Order 
                
                %Set solenoids DC power supply
                fprintf(app.DC_PSU, ['SOURCE:CURRENT ',num2str(Curr),'A']);     %Preset the output current
                fprintf(app.DC_PSU, ['OUTPUT ',num2str(1)]);                    %Switch the output on: 1=On, 0=Off
                pause(0.1)
                
                %Set RF power supply                                            
                %Often it will be impossible to trigger the plasma at the
                %desired power from 0W with a perfect match.
                %Instead, set the matching for the desired power and then
                %tune the frequency for the plasma to trigger at 50W. Then
                %reset the power and frequency to the desired one.
                fprintf(app.RF_PSU, ['SP',sprintf('%04d',50)]);                 %SET RF POWER need to be formated as a XXXX string
                fprintf(app.RF_PSU, ['SF',num2str(Ftrig*1e3)]);                 %SET FREQUENCY in kHz
                fprintf(app.RF_PSU, 'ER');                                      %ENABLE RF
                pause(0.25)
                fprintf(app.RF_PSU, ['SP',sprintf('%04d',Pset)]);               %SET RF POWER need to be formated as a XXXX string
                pause(0.25)
                fprintf(app.RF_PSU, ['SF',num2str(27.12*1e3)]);                 %SET FREQUENCY in kHz, fixed at 27.12MHz
                
            else
                
                %Set RF power supply                                       %[W]
                fprintf(app.RF_PSU, ['SP',sprintf('%04d',Pset)]);               %SET RF POWER need to be formated as a XXXX string
                fprintf(app.RF_PSU, ['SF',num2str(Ftrig*1e3)]);                 %SET FREQUENCY in kHz
                fprintf(app.RF_PSU, 'ER');                                      %ENABLE RF
                pause(0.25)
                
                %Set solenoids DC power supply
                %Ramping up the current from 0 to Curr seems to help with
                %getting a discharge with perfect match
                RampCurr = linspace(0,Curr,10);
                for n=1:length(RampCurr)
                    %Set solenoids DC power supply
                    fprintf(app.DC_PSU, ['SOURCE:CURRENT ',num2str(RampCurr(n)),'A']);     %Preset the output current
                    fprintf(app.DC_PSU, ['OUTPUT ',num2str(1)]);                  %Switch the output on: 1=On, 0=Off
                    pause(0.1)
                end
                pause(0.5)
                fprintf(app.RF_PSU, ['SF',num2str(27.12*1e3)]);                 %SET FREQUENCY in kHz, fixed at 27.12MHz

            end
               
        end

        % Button pushed function: PlasmaOffButton
        function PlasmaOffButtonPushed(app, event)
            
            %Turno off RF power supply
            fprintf(app.RF_PSU, 'DR');                                      %DISABLE RF
            pause(0.1)
            
            %Turn off DC power suppy
            fprintf(app.DC_PSU, ['OUTPUT ',num2str(0)]);                    %Switch the output on: 1=On, 0=Off

        end

        % Button pushed function: GoHomeWestButton
        function GoHomeWestButtonPushed(app, event)
            
            app.StatusEditField.Value='Probe Going Home West';
            
            directionPHomeW = num2str(1);
            distancePHomeW = num2str(95);
            
            % command in the format <MotorID,direction,distance> as string, integer and double
            %L is for the linear motion, R is for the rotation
            %1 means West
            %0 means East

            StepperCommand = strcat("<","L",",",directionPHomeW,",",distancePHomeW,">");
            fprintf(app.ProbArduino,StepperCommand);

        end

        % Button pushed function: GoHomeEastButton
        function GoHomeEastButtonPushed(app, event)
            
            app.StatusEditField.Value='Probe Going Home East';
            
            directionPHomeE = num2str(0);
            distancePHomeE = num2str(95);
            
            % command in the format <MotorID,direction,distance> as string, integer and double
            %L is for the linear motion, R is for the rotation
            %1 means West
            %0 means East

            StepperCommand = strcat("<","L",",",directionPHomeE,",",distancePHomeE,">");
            fprintf(app.ProbArduino,StepperCommand);  
            
        end

        % Button pushed function: GoHomeButton
        function GoHomeButtonPushed(app, event)
            
            app.StatusEditField.Value='Probe Going Home';
            
            directionPHome = num2str(app.Direction_2.Value);
            distancePHome = num2str(95);
            
            % command in the format <MotorID,direction,distance> as string, integer and double
            %L is for the linear motion, R is for the rotation
            %1 means West
            %0 means East

            StepperCommand = strcat("<","L",",",directionPHome,",",distancePHome,">");
            fprintf(app.ProbArduino,StepperCommand);
            
        end

        % Button pushed function: MoveButton
        function MoveButtonPushed(app, event)
            
            app.StatusEditField.Value='Probe is moving';
            
            directionP = num2str(app.Direction_2.Value);
            distanceP = num2str(app.MovebycmEditField.Value);

            % command in the format <MotorID,direction,distance> as string, integer and double
            %L is for the linear motion, R is for the rotation
            %1 means West
            %0 means East
            
            pause(0.5);
            StepperCommand = strcat("<","L",",",directionP,",",distanceP,">");
            fprintf(app.ProbArduino,StepperCommand); 
            
        end

        % Button pushed function: ChangeButton
        function ChangeButtonPushed(app, event)
            %Allows updating of the current save directory
            selpath=uigetdir(app.SaveDirectoryEditField.Value,'Select new save directory');
           
            %Updates the directory in the text field
            %Updates the directory in the text field
            if selpath==0
                return;
            else
                app.SaveDirectoryEditField.Value=selpath;
            end
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            to_file(:,1)=reshape(app.Stime,[],1);
            to_file(:,2)=reshape(app.VAnt,[],1);
            to_file(:,3)=reshape(app.VB,[],1);
            to_file(:,4)=reshape(app.VE,[],1);

            NumberMeas=length(app.VB);
            to_file(1,5)=NumberMeas;

            cd(app.SaveDirectoryEditField.Value) %goes to the directory in the text field

            [file,path]=uiputfile('*.Bdot','Save Bdot results'); %gets the path and filename
%             dlmwrite([path file], hdr, ' ');
            dlmwrite([path file],to_file,'\t'); %does the saving
           
%             app.DataSaveCell(1,1) = cellstr('Time');
%             app.DataSaveCell(2:length(app.Stime)+1,1) = num2cell(app.Stime);
%             app.DataSaveCell(1,2) = cellstr('VAnt');
%             app.DataSaveCell(2:length(app.VAnt)+1,2) = num2cell(app.VAnt);
%             app.DataSaveCell(1,3) = cellstr('VB');
%             app.DataSaveCell(2:length(app.VB)+1,3) = num2cell(app.VB);
%             app.DataSaveCell(1,4) = cellstr('VE');
%             app.DataSaveCell(2:length(app.VE)+1,4) = num2cell(app.VE);
%            
%             %cd(app.SaveDirectoryEditField.Value) %goes to the directory in the text field
%             %path = 'C:\Users\PPLab\Desktop\Huia EP Measurements\DataSave\Argon\Loop_2DScan_200W_25-2A_10SCCM_zb-30cm_23-12-21\20deg\';
%             path = app.SaveDirectoryEditField.Value;
% 
%             writecell(app.DataSaveCell,[path file],'Delimiter','\t');
        end

        % Button pushed function: SolMoveButton
        function SolMoveButtonPushed(app, event)
            app.StatusEditField.Value='Solenoids moving';
          
            direction = app.SolDirection.Value;
            distance = num2str(app.SolMovebycmEditField.Value);
            
            % command in the format <direction,distance> as integer and as double
            %1 means towards the antenna
            %0 means away from the antenna
            StepperCommand = strcat("<",direction,",",distance,">");
            fprintf(app.SolArduino,StepperCommand);
            
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
             app.StatusEditField.Value='Probe rotating';
                            
             DirRot = app.Direction_3.Value;
             Angle =  num2str(app.RotatebydegEditField.Value);             %[deg] desired angle of movement from current position

             % command in the format <MotorID,direction,angle> as string, integer and double
             %L is for the linear motor, R is for the rotation motor
             %0 is clockwise when looking along B field
             %1 is anticlockwise
             
             StepperCommand = strcat("<","R",",",DirRot,",",Angle,">");
             fprintf(app.ProbArduino,StepperCommand);  
        end

        % Button pushed function: KILLSCANButton
        function KILLSCANButtonPushed(app, event)
            app.StatusEditField.Value='Killing the scan!';
            %For now, this button only kills running scan by forcing the
            %for loop in Yougottomoveit to stop (it kills the connection
            %with Arduino, causing an error in the program)
            %Turno off RF power supply
            fprintf(app.RF_PSU, 'DR');                                      %DISABLE RF
            pause(0.1)
            
            %Turn off DC power suppy
            fprintf(app.DC_PSU, ['OUTPUT ',num2str(0)]);                    %Switch the output on: 1=On, 0=Off

            fclose(app.ProbArduino);
        end

        % Button pushed function: SuperRotateButton
        function SuperRotateButtonPushed(app, event)
            app.StatusEditField.Value='Rotating the probe and returning to Antenna';
            %'Move the probe to Home East, rotate probe, move to antenna'
            directionPHomeE = num2str(0);
            distancePHomeE = num2str(95);
            DirRot = app.Direction_3.Value;
            Angle =  num2str(app.RotatebydegEditField.Value);             %[deg] desired angle of movement from current position
            directionP = num2str(1);
            distanceP = num2str(70);
            
            StepperCommand1 = strcat("<","L",",",directionPHomeE,",",distancePHomeE,">"); 
            StepperCommand2 = strcat("<","R",",",DirRot,",",Angle,">");
            StepperCommand3 = strcat("<","L",",",directionP,",",distanceP,">");
            fprintf(app.ProbArduino,StepperCommand1);
            fprintf(app.ProbArduino,StepperCommand2); 
            fprintf(app.ProbArduino,StepperCommand3); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Floating Potential')
            xlabel(app.UIAxes, 't [s]')
            ylabel(app.UIAxes, '[V]')
            zlabel(app.UIAxes, 'Z')
            app.InitialiseButton = uibutton(app.UIFigure, 'push');
            app.InitialiseButton.ButtonPushedFcn = createCallbackFcn(app, @InitialiseButtonPushed, true);
            app.InitialiseButton.Text = 'Initialise';
            app.BdotAxialScanv10Label = uilabel(app.UIFigure);
            app.BdotAxialScanv10Label.Text = 'B-dot Axial Scan v1.0';
            app.ProbeScanParametersPanel = uipanel(app.UIFigure);
            app.ProbeScanParametersPanel.Title = 'Probe Scan Parameters';
            app.StartPositioncmEditFieldLabel = uilabel(app.ProbeScanParametersPanel);
            app.StartPositioncmEditFieldLabel.Text = 'Start Position [cm]';
            app.StartPositionField = uieditfield(app.ProbeScanParametersPanel, 'numeric');
            app.EndPositioncmEditFieldLabel = uilabel(app.ProbeScanParametersPanel);
            app.EndPositioncmEditFieldLabel.Text = 'End Position [cm]';
            app.EndPositionField = uieditfield(app.ProbeScanParametersPanel, 'numeric');
            app.IncrementcmEditFieldLabel = uilabel(app.ProbeScanParametersPanel);
            app.IncrementcmEditFieldLabel.Text = 'Increment [cm]';
            app.IncrementField = uieditfield(app.ProbeScanParametersPanel, 'numeric');
            app.IncrementField.Value = 2;
            app.DirectionLabel = uilabel(app.ProbeScanParametersPanel);
            app.DirectionLabel.Text = 'Direction';
            app.Direction = uidropdown(app.ProbeScanParametersPanel);
            app.Direction.Items = {'East', 'West'};
            app.Direction.ItemsData = [0 1];
            app.Direction.Value = 0;
            app.GoHomeEastButton = uibutton(app.ProbeScanParametersPanel, 'push');
            app.GoHomeEastButton.ButtonPushedFcn = createCallbackFcn(app, @GoHomeEastButtonPushed, true);
            app.GoHomeEastButton.Text = 'Go Home East';
            app.GoHomeWestButton = uibutton(app.ProbeScanParametersPanel, 'push');
            app.GoHomeWestButton.ButtonPushedFcn = createCallbackFcn(app, @GoHomeWestButtonPushed, true);
            app.GoHomeWestButton.Text = 'Go Home West';
            app.YougottoMoveitButton = uibutton(app.UIFigure, 'push');
            app.YougottoMoveitButton.ButtonPushedFcn = createCallbackFcn(app, @YougottoMoveitButtonPushed, true);
            app.YougottoMoveitButton.Text = 'You got to Move it';
            app.StatusEditFieldLabel = uilabel(app.UIFigure);
            app.StatusEditFieldLabel.Text = 'Status:';
            app.StatusEditField = uieditfield(app.UIFigure, 'text');
            app.PlasmaParametersPanel = uipanel(app.UIFigure);
            app.PlasmaParametersPanel.Title = 'Plasma Parameters';
            app.RFPowerSetWLabel = uilabel(app.PlasmaParametersPanel);
            app.RFPowerSetWLabel.Text = 'RF Power Set [W]';
            app.Power = uieditfield(app.PlasmaParametersPanel, 'numeric');
            app.Power.Value = 200;
            app.CoilsCurrentAEditFieldLabel = uilabel(app.PlasmaParametersPanel);
            app.CoilsCurrentAEditFieldLabel.Text = 'Coils Current [A]';
            app.CoilsCurrentAEditField = uieditfield(app.PlasmaParametersPanel, 'numeric');
            app.CoilsCurrentAEditField.Value = 12.6;
            app.MatchingTestPlasmaOnButton = uibutton(app.PlasmaParametersPanel, 'push');
            app.MatchingTestPlasmaOnButton.ButtonPushedFcn = createCallbackFcn(app, @MatchingTestPlasmaOnButtonPushed, true);
            app.MatchingTestPlasmaOnButton.Text = 'Matching Test (Plasma On)';
            app.PlasmaOffButton = uibutton(app.PlasmaParametersPanel, 'push');
            app.PlasmaOffButton.ButtonPushedFcn = createCallbackFcn(app, @PlasmaOffButtonPushed, true);
            app.PlasmaOffButton.Text = 'Plasma Off';
            app.IgnitionOrderDropDownLabel = uilabel(app.PlasmaParametersPanel);
            app.IgnitionOrderDropDownLabel.Text = 'Ignition Order';
            app.IgnitionOrderDropDown = uidropdown(app.PlasmaParametersPanel);
            app.IgnitionOrderDropDown.Items = {'Bfield First', 'Plasma First'};
            app.IgnitionOrderDropDown.ItemsData = [1 0];
            app.IgnitionOrderDropDown.Value = 0;
            app.Frequency50WMHzEditFieldLabel = uilabel(app.PlasmaParametersPanel);
            app.Frequency50WMHzEditFieldLabel.Text = 'Frequency @ 50W [MHz]';
            app.Frequency50WMHzEditField = uieditfield(app.PlasmaParametersPanel, 'numeric');
            app.Frequency50WMHzEditField.Value = 27.12;
            app.ProbeIndependantPositionPanel = uipanel(app.UIFigure);
            app.ProbeIndependantPositionPanel.Title = 'Probe Independant Position ';
            app.DirectionLabel_2 = uilabel(app.ProbeIndependantPositionPanel);
            app.DirectionLabel_2.Text = 'Direction';
            app.Direction_2 = uidropdown(app.ProbeIndependantPositionPanel);
            app.Direction_2.Items = {'East', 'West'};
            app.Direction_2.ItemsData = [0 1];
            app.Direction_2.Value = 1;
            app.MovebycmEditFieldLabel = uilabel(app.ProbeIndependantPositionPanel);
            app.MovebycmEditFieldLabel.Text = 'Move by [cm]';
            app.MovebycmEditField = uieditfield(app.ProbeIndependantPositionPanel, 'numeric');
            app.MovebycmEditField.Value = 60;
            app.GoHomeButton = uibutton(app.ProbeIndependantPositionPanel, 'push');
            app.GoHomeButton.ButtonPushedFcn = createCallbackFcn(app, @GoHomeButtonPushed, true);
            app.GoHomeButton.Text = 'Go Home';
            app.MoveButton = uibutton(app.ProbeIndependantPositionPanel, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Text = 'Move';
            app.SaveDirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.SaveDirectoryEditFieldLabel.Text = 'Save Directory:';
            app.SaveDirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.SaveDirectoryEditField.Value = 'C:\Users\PPLab\Desktop\Huia Bdot\DataSave\NoPlasmaUoAHybridC_25W_z-10cm_30cm\';
            app.ChangeButton = uibutton(app.UIFigure, 'push');
            app.ChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeButtonPushed, true);
            app.ChangeButton.Text = 'Change';
            app.SaveDataButton = uibutton(app.UIFigure, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.SolenoidsPositionControlPanel = uipanel(app.UIFigure);
            app.SolenoidsPositionControlPanel.Title = 'Solenoids Position Control';
            app.DirectionLabel_3 = uilabel(app.SolenoidsPositionControlPanel);
            app.DirectionLabel_3.Text = 'Direction';
            app.SolDirection = uidropdown(app.SolenoidsPositionControlPanel);
            app.SolDirection.Items = {'East', 'West'};
            app.SolDirection.ItemsData = {'0', '1'};
            app.SolDirection.Value = '1';
            app.MovebycmEditField_2Label = uilabel(app.SolenoidsPositionControlPanel);
            app.MovebycmEditField_2Label.Text = 'Move by [cm]';
            app.SolMovebycmEditField = uieditfield(app.SolenoidsPositionControlPanel, 'numeric');
            app.SolMovebycmEditField.Value = 10;
            app.SolMoveButton = uibutton(app.SolenoidsPositionControlPanel, 'push');
            app.SolMoveButton.ButtonPushedFcn = createCallbackFcn(app, @SolMoveButtonPushed, true);
            app.SolMoveButton.Text = 'Move';
            app.ProbeRadialPositionPanel = uipanel(app.UIFigure);
            app.ProbeRadialPositionPanel.Title = 'Probe Radial Position ';
            app.DirectionLabel_4 = uilabel(app.ProbeRadialPositionPanel);
            app.DirectionLabel_4.Text = 'Direction';
            app.Direction_3 = uidropdown(app.ProbeRadialPositionPanel);
            app.Direction_3.Items = {'Clockwise', 'Anticlockwise'};
            app.Direction_3.ItemsData = {'0', '1'};
            app.Direction_3.Value = '0';
            app.RotatebydegEditFieldLabel = uilabel(app.ProbeRadialPositionPanel);
            app.RotatebydegEditFieldLabel.Text = 'Rotate by [deg]';
            app.RotatebydegEditField = uieditfield(app.ProbeRadialPositionPanel, 'numeric');
            app.RotatebydegEditField.Value = 10;
            app.RotateButton = uibutton(app.ProbeRadialPositionPanel, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Text = 'Rotate';
            app.SuperRotateButton = uibutton(app.ProbeRadialPositionPanel, 'push');
            app.SuperRotateButton.ButtonPushedFcn = createCallbackFcn(app, @SuperRotateButtonPushed, true);
            app.SuperRotateButton.Text = 'Super Rotate';
            app.OscilloscopeSettingsPanel = uipanel(app.UIFigure);
            app.OscilloscopeSettingsPanel.Title = 'Oscilloscope Settings';
            app.Ch1PPRangeVEditFieldLabel = uilabel(app.OscilloscopeSettingsPanel);
            app.Ch1PPRangeVEditFieldLabel.Text = 'Ch1 PP Range [V]';
            app.Ch1PPRangeVEditField = uieditfield(app.OscilloscopeSettingsPanel, 'numeric');
            app.Ch1PPRangeVEditField.Value = 1600;
            app.Ch2PPRangeVEditFieldLabel = uilabel(app.OscilloscopeSettingsPanel);
            app.Ch2PPRangeVEditFieldLabel.Text = 'Ch2 PP Range [V]';
            app.Ch2PPRangeVEditField = uieditfield(app.OscilloscopeSettingsPanel, 'numeric');
            app.Ch2PPRangeVEditField.Value = 4;
            app.Ch3PPRangeVEditFieldLabel = uilabel(app.OscilloscopeSettingsPanel);
            app.Ch3PPRangeVEditFieldLabel.Text = 'Ch3 PP Range [V]';
            app.Ch3PPRangeVEditField = uieditfield(app.OscilloscopeSettingsPanel, 'numeric');
            app.Ch3PPRangeVEditField.Value = 4;
            app.KILLSCANButton = uibutton(app.UIFigure, 'push');
            app.KILLSCANButton.ButtonPushedFcn = createCallbackFcn(app, @KILLSCANButtonPushed, true);
            app.KILLSCANButton.Text = 'KILL SCAN';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Bdot_AxialScan_V1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end